classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure             % UI Figure
        Label          matlab.ui.control.Label      % Analiza spectrala a l...
        LabelDropDown  matlab.ui.control.Label      % Serie rulment
        DropDown       matlab.ui.control.DropDown   % , Option 2, Option 3,...
        Label2         matlab.ui.control.Label      % Selecteaza rulmentul ...
        Panel          matlab.ui.container.Panel    % Frecventele rulmentul...
        Panel2         matlab.ui.container.Panel    % Dimensiunile rulmentu...
        Button         matlab.ui.control.Button     % Inregistreaza zgomot
        TabGroup       matlab.ui.container.TabGroup % Analiza spectrala, Co...
        Tab            matlab.ui.container.Tab      % Analiza spectrala
        UIAxes         matlab.ui.control.UIAxes     % Amplitudine frecventa
        UIAxes2        matlab.ui.control.UIAxes     % Putere frecventa
        Tab2           matlab.ui.container.Tab      % Comparatie
        Button2        matlab.ui.control.Button     % Exit
        Button3        matlab.ui.control.Button     % Reda inregistrarea
        LabelDropDown2 matlab.ui.control.Label      % Limba
        DropDown2      matlab.ui.control.DropDown   % Romana, English, French
    end

    
    properties (Access = public)
        file % loaded audio file
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button button pushed function
        function loadAudioFile(app)
            file = loadFile();
            app.file = strcat('uploaded audio/', file);
        end

        % Button3 button pushed function
        function playAudio(app)
            [y, Fs] = audioread(app.file);
            % sound(y, Fs);
            N = length(y(:,1));
            Y = fft(y(:,1));
            plot_S(1/Fs, Y, app.UIAxes);
            plot_S(1/Fs, (abs(Y).^2)/N, app.UIAxes2);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Analiza spectrala a lagarelor cu rulmenti';
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = 'Serie rulment';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.DropDown.Value = '';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Selecteaza rulmentul din biblioteca';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Frecventele rulmentului selectat';
            app.Panel.Units = 'pixels';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Dimensiunile rulmentului selectat';
            app.Panel2.Units = 'pixels';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @loadAudioFile);
            app.Button.Text = 'Inregistreaza zgomot';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Analiza spectrala';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Amplitudine frecventa');
            xlabel(app.UIAxes, 'frecventa');
            ylabel(app.UIAxes, 'amplitudine');
            app.UIAxes2 = uiaxes(app.Tab);
            title(app.UIAxes2, 'Putere frecventa');
            xlabel(app.UIAxes2, 'frecventa');
            ylabel(app.UIAxes2, 'putere');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Comparatie';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.Text = 'Exit';
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @playAudio);
            app.Button3.Text = 'Reda inregistrarea';
            app.LabelDropDown2 = uilabel(app.UIFigure);
            app.LabelDropDown2.Text = 'Limba';
            app.DropDown2 = uidropdown(app.UIFigure);
            app.DropDown2.Items = {'Romana', 'English', 'French'};
            app.DropDown2.Value = 'Romana';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end